classdef FirstApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        TimeperiodGaugeLabel           matlab.ui.control.Label
        TimeperiodGauge                matlab.ui.control.Gauge
        InputsPanel                    matlab.ui.container.Panel
        FPSEditFieldLabel              matlab.ui.control.Label
        FPSEditField                   matlab.ui.control.NumericEditField
        SimulationtimesEditFieldLabel  matlab.ui.control.Label
        SimulationtimesEditField       matlab.ui.control.NumericEditField
        LengthmEditFieldLabel          matlab.ui.control.Label
        LengthmEditField               matlab.ui.control.NumericEditField
        StartingangledegKnobLabel      matlab.ui.control.Label
        StartingangledegKnob           matlab.ui.control.Knob
        AccelerationduetogravitymsEditFieldLabel  matlab.ui.control.Label
        AccelerationduetogravitymsEditField  matlab.ui.control.NumericEditField
        DropButton                     matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
    end


    methods (Access = private)

        % Button pushed function: DropButton
        function DropButtonPushed(app, event)
            fps = app.FPSEditField.Value;
            simtime = app.SimulationtimesEditField.Value;
            l = app.LengthmEditField.Value;
            g = app.AccelerationduetogravitymsEditField.Value;
            theta_drop = app.StartingangledegKnob.Value;
            
            totalframes=fps*simtime;
            t = transpose(linspace (0,simtime,totalframes));
            theta = zeros(length(t),1);
            T = 2*pi*sqrt(l/g);
            app.TimeperiodGauge.Value = T;
            for i=1:length(t)
                theta(i,1) = theta_drop*cos((2*pi*t(i,1))/T);
            end
            plot(app.UIAxes,t,theta);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Pendulum swing')
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'Angle [deg]')
            app.TimeperiodGaugeLabel = uilabel(app.UIFigure);
            app.TimeperiodGaugeLabel.Text = 'Time period';
            app.TimeperiodGauge = uigauge(app.UIFigure, 'circular');
            app.TimeperiodGauge.Limits = [0 5];
            app.InputsPanel = uipanel(app.UIFigure);
            app.InputsPanel.Title = 'Inputs';
            app.FPSEditFieldLabel = uilabel(app.InputsPanel);
            app.FPSEditFieldLabel.Text = 'FPS';
            app.FPSEditField = uieditfield(app.InputsPanel, 'numeric');
            app.FPSEditField.Value = 30;
            app.SimulationtimesEditFieldLabel = uilabel(app.InputsPanel);
            app.SimulationtimesEditFieldLabel.Text = 'Simulation time [s]';
            app.SimulationtimesEditField = uieditfield(app.InputsPanel, 'numeric');
            app.SimulationtimesEditField.Value = 10;
            app.LengthmEditFieldLabel = uilabel(app.InputsPanel);
            app.LengthmEditFieldLabel.Text = 'Length [m]';
            app.LengthmEditField = uieditfield(app.InputsPanel, 'numeric');
            app.LengthmEditField.Value = 1;
            app.StartingangledegKnobLabel = uilabel(app.InputsPanel);
            app.StartingangledegKnobLabel.Text = 'Starting angle [deg]';
            app.StartingangledegKnob = uiknob(app.InputsPanel, 'continuous');
            app.StartingangledegKnob.Limits = [0 179];
            app.StartingangledegKnob.Value = 90;
            app.AccelerationduetogravitymsEditFieldLabel = uilabel(app.InputsPanel);
            app.AccelerationduetogravitymsEditFieldLabel.Text = 'Acceleration due to gravity [m/s^"]';
            app.AccelerationduetogravitymsEditField = uieditfield(app.InputsPanel, 'numeric');
            app.AccelerationduetogravitymsEditField.Value = 9.81;
            app.DropButton = uibutton(app.InputsPanel, 'push');
            app.DropButton.ButtonPushedFcn = createCallbackFcn(app, @DropButtonPushed, true);
            app.DropButton.Text = 'Drop';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
        end
    end

    methods (Access = public)

        % Construct app
        function app = FirstApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end